classdef garNET_explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        garNET_explorer_GUI  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        NotGarnetButton      matlab.ui.control.Button
        LoadGrainsButton     matlab.ui.control.Button
        EditField2           matlab.ui.control.EditField
        OtherButton          matlab.ui.control.Button
        SkipButton           matlab.ui.control.Button
        MultipleButton       matlab.ui.control.Button
        AtollButton          matlab.ui.control.Button
        IntactButton         matlab.ui.control.Button
        LoadFolderButton     matlab.ui.control.Button
        Tree                 matlab.ui.container.Tree
        viewer_3D            matlab.ui.container.Panel
        SlideId              matlab.ui.control.NumericEditField
        ImageIDLabel         matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        ViewDropDown         matlab.ui.control.DropDown
        ViewDropDownLabel    matlab.ui.control.Label
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        GrainData       % Description
        Directories

        WaitBar
    end
    
    methods (Access = private)
        
        function loadGrains(app,filename,pathname)
            
            Nb = length(app.GrainData.Names);

            app.Directories.Source = pathname;

            for i = 1:length(filename)
                app.GrainData.Names{Nb+i} = filename{i};
                app.GrainData.Data(Nb+i).Data = readNPY(fullfile(pathname,filename{i}));

                p = uitreenode(app.Tree,'Text',char(app.GrainData.Names{Nb+i}),'NodeData',[Nb+i]);

            end

        end
        
        function PlotGrain(app,Idx)

            SelectedGrain = app.Tree.SelectedNodes.NodeData;
            
            if Idx > 0
                SliderValue = round(Idx);
            else
                SliderValue = round(app.Slider.Value);
            end

            switch app.ViewDropDown.Value

                case 'X-view'
                    if Idx > 0
                        app.UIAxes.Children.CData = squeeze(app.GrainData.Data(SelectedGrain).Data(:,SliderValue,:));
                    else
                        imagesc(app.UIAxes,squeeze(app.GrainData.Data(SelectedGrain).Data(:,SliderValue,:)));
                    end

                case 'Y-view'
                    if Idx > 0
                        app.UIAxes.Children.CData = squeeze(app.GrainData.Data(SelectedGrain).Data(:,:,SliderValue));
                    else
                        imagesc(app.UIAxes,squeeze(app.GrainData.Data(SelectedGrain).Data(:,:,SliderValue)));
                    end

                case 'Z-view'
                    if Idx > 0
                        app.UIAxes.Children.CData = squeeze(app.GrainData.Data(SelectedGrain).Data(SliderValue,:,:));
                    else
                        imagesc(app.UIAxes,squeeze(app.GrainData.Data(SelectedGrain).Data(SliderValue,:,:)));
                    end

            end

            app.SlideId.Value = SliderValue;

            colormap(app.UIAxes,"gray")
            axis(app.UIAxes,'image');

            drawnow

            %keyboard
            
        end
        
        function SetSliderLimitsAuto(app)

            SelectedGrain = app.Tree.SelectedNodes.NodeData;

            switch app.ViewDropDown.Value

                case 'X-view'
                    app.Slider.Limits = [1,size(app.GrainData.Data(SelectedGrain).Data,2)];

                case 'Y-view'
                    app.Slider.Limits = [1,size(app.GrainData.Data(SelectedGrain).Data,3)];

                case 'Z-view'
                    app.Slider.Limits = [1,size(app.GrainData.Data(SelectedGrain).Data,1)];

            end

            app.Slider.Value = round((app.Slider.Limits(2)-1)/2);

        end
        
        function Display3Dview(app)
            
            %cla(app.viewer_3D)
            %reset(app.UIAxes_2)
            
            cla(app.UIAxes_2,'reset')
            delete(app.viewer_3D);

            app.viewer_3D = uipanel(app.GridLayout);
            app.viewer_3D.Layout.Row = [14 23];
            app.viewer_3D.Layout.Column = [23 32];

            SelectedGrain = app.Tree.SelectedNodes.NodeData;

            volshow(app.GrainData.Data(SelectedGrain).Data, 'parent',app.viewer_3D);

            R = squeeze(sum(app.GrainData.Data(SelectedGrain).Data,1));
            G = squeeze(sum(app.GrainData.Data(SelectedGrain).Data,2));
            B = squeeze(sum(app.GrainData.Data(SelectedGrain).Data,3));
            
            R = imresize(R,[64,64]);
            G = imresize(G,[64,64]);
            B = imresize(B,[64,64]);

            R = rescale(R,0,1);
            G = rescale(G,0,1);
            B = rescale(B,0,1);

            rgbimg=cat(3,R,G,B);
            imshow(rgbimg,'Parent',app.UIAxes_2)

        end
        
        function TakeCareOfThisFella(app,FolderName)
            app.WaitBar = uiprogressdlg(app.garNET_explorer_GUI);
            %
            SelectedGrain = app.Tree.SelectedNodes.NodeData;

            if ~exist(fullfile(app.Directories.Working,'train_data',FolderName))
                [Success,Message,MessageID] = mkdir(fullfile(app.Directories.Working,'train_data',FolderName));
            end

            h = copyfile(fullfile(app.Directories.Source,app.GrainData.Names{SelectedGrain}),fullfile(app.Directories.Working,'train_data',FolderName,app.GrainData.Names{SelectedGrain}),'f');

            disp([app.GrainData.Names{SelectedGrain},' ',FolderName])
            dlmwrite(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),[app.GrainData.Names{SelectedGrain},' ',FolderName],'-append','delimiter','')

%             f2 = figure;
%             ax2 = gca;
%             copyobj(app.UIAxes_2.Children,ax2);
% 
%             saveas(f2,fullfile(app.Directories.Working,'train_data',[FolderName,app.GrainData.Names{SelectedGrain},'.png']))
%             close(f2)

            app.GrainData.Names(SelectedGrain) = [];
            app.GrainData.Data(SelectedGrain) = [];

            app.Tree.Children(SelectedGrain).delete;

            for i = 1:length(app.Tree.Children)
                app.Tree.Children(i).NodeData = i;
            end

            if SelectedGrain <= length(app.GrainData.Names)
                app.Tree.SelectedNodes = app.Tree.Children(SelectedGrain);
                SetSliderLimitsAuto(app);
                PlotGrain(app,0);
                Display3Dview(app);
            end
            close(app.WaitBar)
        end
        
        function LoadSelectedFiles(app,filename,pathname)

            ClassifiedGrains = '';
            Count = 0;
            fid = fopen(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),'r');
            while 1
                TheLine = fgetl(fid);
                if ~isequal(TheLine,-1)
                    TheLineStr = strread(TheLine,'%s');
                    Count = Count + 1;
                    ClassifiedGrains{Count} = TheLineStr{1};
                else
                    break
                end
            end
            fclose(fid);

            Eliminate = zeros(size(filename));
            for i = 1:length(filename)
                IsClassified = find(ismember(ClassifiedGrains,filename{i}));
                if IsClassified
                    Eliminate(i) = 1;
                end
            end
            
            filename(find(Eliminate)) = [];

            loadGrains(app,filename,pathname);

            app.Tree.SelectedNodes = app.Tree.Children(1);

            SetSliderLimitsAuto(app);
            PlotGrain(app,0);
            Display3Dview(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %

            app.Directories.Working = cd;


            app.GrainData.Names = {};
            app.GrainData.Data(1).Data = [];
            

            [Success,Message,MessageID] = mkdir('train_data');
            cd train_data/
            [Success,Message,MessageID] = mkdir('intact');
            [Success,Message,MessageID] = mkdir('atoll');
            [Success,Message,MessageID] = mkdir('multiple');
            [Success,Message,MessageID] = mkdir('notGarnet');
            cd ..

            % keyboard
            clc

            if ~exist(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'))
                dlmwrite(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),[]);
            end


            movegui(app.garNET_explorer_GUI,"center");

        end

        % Button pushed function: LoadGrainsButton
        function LoadGrainsButtonPushed(app, event)
            
            %
            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            [filename, pathname] = uigetfile('*.npy', 'Pick files','MultiSelect','on');
            delete(f);
            figure(app.garNET_explorer_GUI)
            if isequal(filename,0)
                return
            end

            LoadSelectedFiles(app,filename,pathname);

            ClassifiedGrains = '';
            Count = 0;
            fid = fopen(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),'r');
            while 1
                TheLine = fgetl(fid);
                if ~isequal(TheLine,-1)
                    TheLineStr = strread(TheLine,'%s');
                    Count = Count + 1;
                    ClassifiedGrains{Count} = TheLineStr{1};
                else
                    break
                end
            end
            fclose(fid);

            Eliminate = zeros(size(filename));
            for i = 1:length(filename)
                IsClassified = find(ismember(ClassifiedGrains,filename{i}));
                if IsClassified
                    Eliminate(i) = 1;
                end
            end
            
            filename(find(Eliminate)) = [];

            loadGrains(app,filename,pathname);

            app.Tree.SelectedNodes = app.Tree.Children(1);

            SetSliderLimitsAuto(app);
            PlotGrain(app,0);
            Display3Dview(app);
           

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.WaitBar = uiprogressdlg(app.garNET_explorer_GUI);
            
            selectedNodes = app.Tree.SelectedNodes;

            SetSliderLimitsAuto(app);
            PlotGrain(app,0);
            Display3Dview(app);

            close(app.WaitBar)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            PlotGrain(app,0);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            PlotGrain(app,changingValue);
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            PlotGrain(app,0);
        end

        % Button pushed function: IntactButton
        function IntactButtonPushed(app, event)
            TakeCareOfThisFella(app,'intact');
        end

        % Button pushed function: AtollButton
        function AtollButtonPushed(app, event)
            TakeCareOfThisFella(app,'atoll');
        end

        % Button pushed function: MultipleButton
        function MultipleButtonPushed(app, event)
            TakeCareOfThisFella(app,'multiple');
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            SelectedGrain = app.Tree.SelectedNodes.NodeData;
            if SelectedGrain + 1 <= length(app.GrainData.Names)
                app.Tree.SelectedNodes = app.Tree.Children(app.Tree.SelectedNodes.NodeData + 1);
                SetSliderLimitsAuto(app);
                PlotGrain(app,0);
                Display3Dview(app);
            end
        end

        % Button pushed function: OtherButton
        function OtherButtonPushed(app, event)
            TakeCareOfThisFella(app,app.EditField2.Value);
        end

        % Button pushed function: NotGarnetButton
        function NotGarnetButtonPushed(app, event)
            TakeCareOfThisFella(app,'notGarnet');
        end

        % Close request function: garNET_explorer_GUI
        function garNET_explorer_GUICloseRequest(app, event)
            
            diary('off');
            
            delete(app)
            
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
        
        pathname = fullfile(app.Directories.Working,'grains');

        if exist(pathname)
            DataNames = dir('grains/*.npy');
        end

        NbFiles = length(DataNames);

        prompt={'How many grains?'};
        name='Input';
        numlines=1;
        defaultanswer={num2str(NbFiles)};

        answer=inputdlg(prompt,name,numlines,defaultanswer);

        if isempty(answer)
            return
        end
    
        Nb = str2num(answer{1}); 

        p = randperm(NbFiles,Nb);

        filename = cell(Nb,1);

        for i = 1:Nb
            filename{i} = DataNames(p(i)).name;
        end


        LoadSelectedFiles(app,filename,pathname)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.garNET_explorer_GUI = uifigure('Visible', 'off');
            app.garNET_explorer_GUI.Name = 'garNET_explorer';
            app.garNET_explorer_GUI.CloseRequestFcn = createCallbackFcn(app, @garNET_explorer_GUICloseRequest, true);
            app.GridLayout = uigridlayout(app.garNET_explorer_GUI);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ViewDropDownLabel = uilabel(app.GridLayout);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.GridLayout);
            app.ViewDropDown.Items = {'X-view', 'Y-view', 'Z-view'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'X-view';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [1 100000];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.ImageIDLabel = uilabel(app.GridLayout);
            app.ImageIDLabel.Text = 'Image ID';
            app.SlideId = uieditfield(app.GridLayout, 'numeric');
            app.viewer_3D = uipanel(app.GridLayout);
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.LoadFolderButton = uibutton(app.GridLayout, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.IntactButton = uibutton(app.GridLayout, 'push');
            app.IntactButton.ButtonPushedFcn = createCallbackFcn(app, @IntactButtonPushed, true);
            app.IntactButton.Text = 'Intact';
            app.AtollButton = uibutton(app.GridLayout, 'push');
            app.AtollButton.ButtonPushedFcn = createCallbackFcn(app, @AtollButtonPushed, true);
            app.AtollButton.Text = 'Atoll';
            app.MultipleButton = uibutton(app.GridLayout, 'push');
            app.MultipleButton.ButtonPushedFcn = createCallbackFcn(app, @MultipleButtonPushed, true);
            app.MultipleButton.Text = 'Multiple';
            app.SkipButton = uibutton(app.GridLayout, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.OtherButton = uibutton(app.GridLayout, 'push');
            app.OtherButton.ButtonPushedFcn = createCallbackFcn(app, @OtherButtonPushed, true);
            app.OtherButton.Text = 'Other';
            app.EditField2 = uieditfield(app.GridLayout, 'text');
            app.EditField2.Value = 'MyName';
            app.LoadGrainsButton = uibutton(app.GridLayout, 'push');
            app.LoadGrainsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGrainsButtonPushed, true);
            app.LoadGrainsButton.Text = 'Load Grains';
            app.NotGarnetButton = uibutton(app.GridLayout, 'push');
            app.NotGarnetButton.ButtonPushedFcn = createCallbackFcn(app, @NotGarnetButtonPushed, true);
            app.NotGarnetButton.Text = 'Not Garnet';
            % Show the figure after all components are created
            app.garNET_explorer_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = garNET_explorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.garNET_explorer_GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.garNET_explorer_GUI)
        end
    end
end